classdef polynoms < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PolynomsUIFigure  matlab.ui.Figure
        Label             matlab.ui.control.Label
        edtPl1            matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        edtPl2            matlab.ui.control.EditField
        Label_3           matlab.ui.control.Label
        polynom1Axes      matlab.ui.control.UIAxes
        polynom2Axes      matlab.ui.control.UIAxes
        f1xrootsLabel     matlab.ui.control.Label
        lblRoots1         matlab.ui.control.Label
        f2xrootsLabel     matlab.ui.control.Label
        lblRoots2         matlab.ui.control.Label
        lblDeconv_buf     matlab.ui.control.Label
        lblDeconv         matlab.ui.control.Label
        findf1Xf2XButton  matlab.ui.control.Button
        gcdButton         matlab.ui.control.Button
        lblGcd            matlab.ui.control.Label
    end

    methods (Access = private)

        % Value changed function: edtPl1
        function edtPl1ValueChanged(app, event)
            value = app.edtPl1.Value;
            syms f1(x);
            f1(x) = str2sym(value);
            fplot(app.polynom1Axes, f1(x));
            
            r = roots(sym2poly(f1(x)));
            app.lblRoots1.Text = cellstr(char(num2str(r)));
        end

        % Value changed function: edtPl2
        function edtPl2ValueChanged(app, event)
            value = app.edtPl2.Value;
            syms f2(x);
            f2(x) = str2sym(value);
            fplot(app.polynom2Axes, f2(x));
            
            r = roots(sym2poly(f2(x)));
            app.lblRoots2.Text = cellstr(char(num2str(r)));
        end

        % Button pushed function: findf1Xf2XButton
        function findf1Xf2XButtonPushed(app, event)
            syms x;
            
            f1(x) = str2sym(app.edtPl1.Value);
            f2(x) = str2sym(app.edtPl2.Value);
               
            p1 = sym2poly(f1(x));
            p2 = sym2poly(f2(x));
            [q, r] = deconv(p1, p2);
            syms Q R
            Q = poly2sym(q, x);
            R = poly2sym(r, x);
               
            app.lblDeconv.Text = "f2(x)*" + sym2cell(Q) + " + " + sym2cell(R);
            
        end

        % Button pushed function: gcdButton
        function gcdButtonPushed(app, event)
            syms x;
            f1(x) = str2sym(app.edtPl1.Value);
            f2(x) = str2sym(app.edtPl2.Value);

            G = gcd(f1(x),f2(x));
            
            app.lblGcd.Text = "" + sym2cell(G);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PolynomsUIFigure = uifigure;
            app.PolynomsUIFigure.Name = 'Polynoms';
            app.Label = uilabel(app.PolynomsUIFigure);
            app.Label.Text = 'Полином 1';
            app.edtPl1 = uieditfield(app.PolynomsUIFigure, 'text');
            app.edtPl1.ValueChangedFcn = createCallbackFcn(app, @edtPl1ValueChanged, true);
            app.Label_2 = uilabel(app.PolynomsUIFigure);
            app.Label_2.Text = 'Полином 2';
            app.edtPl2 = uieditfield(app.PolynomsUIFigure, 'text');
            app.edtPl2.ValueChangedFcn = createCallbackFcn(app, @edtPl2ValueChanged, true);
            app.Label_3 = uilabel(app.PolynomsUIFigure);
            app.Label_3.Text = 'Ведите полиномы:';
            app.polynom1Axes = uiaxes(app.PolynomsUIFigure);
            title(app.polynom1Axes, 'Полином 1')
            xlabel(app.polynom1Axes, 'X')
            ylabel(app.polynom1Axes, 'Y')
            app.polynom2Axes = uiaxes(app.PolynomsUIFigure);
            title(app.polynom2Axes, 'Полином 2')
            xlabel(app.polynom2Axes, 'X')
            ylabel(app.polynom2Axes, 'Y')
            app.f1xrootsLabel = uilabel(app.PolynomsUIFigure);
            app.f1xrootsLabel.Text = 'f1(x) roots ';
            app.lblRoots1 = uilabel(app.PolynomsUIFigure);
            app.lblRoots1.Text = '';
            app.f2xrootsLabel = uilabel(app.PolynomsUIFigure);
            app.f2xrootsLabel.Text = 'f2(x) roots';
            app.lblRoots2 = uilabel(app.PolynomsUIFigure);
            app.lblRoots2.Text = '';
            app.lblDeconv_buf = uilabel(app.PolynomsUIFigure);
            app.lblDeconv_buf.Text = 'f1(x)/f2(x)';
            app.lblDeconv = uilabel(app.PolynomsUIFigure);
            app.lblDeconv.Text = '';
            app.findf1Xf2XButton = uibutton(app.PolynomsUIFigure, 'push');
            app.findf1Xf2XButton.ButtonPushedFcn = createCallbackFcn(app, @findf1Xf2XButtonPushed, true);
            app.findf1Xf2XButton.Text = 'find f1(X)/f2(X)';
            app.gcdButton = uibutton(app.PolynomsUIFigure, 'push');
            app.gcdButton.ButtonPushedFcn = createCallbackFcn(app, @gcdButtonPushed, true);
            app.gcdButton.Text = 'gcd';
            app.lblGcd = uilabel(app.PolynomsUIFigure);
            app.lblGcd.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = polynoms

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PolynomsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PolynomsUIFigure)
        end
    end
end